classdef Parametric_EQ_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        EqualizerTab                    matlab.ui.container.Tab
        Panel_2                         matlab.ui.container.Panel
        shiftingEditField               matlab.ui.control.EditField
        shiftingEditFieldLabel          matlab.ui.control.Label
        FileLabel                       matlab.ui.control.Label
        acoeffTextArea                  matlab.ui.control.TextArea
        acoeffLabel                     matlab.ui.control.Label
        bcoeffTextArea                  matlab.ui.control.TextArea
        bcoeffLabel                     matlab.ui.control.Label
        SendtoanalysisButton            matlab.ui.control.Button
        Panel_4                         matlab.ui.container.Panel
        StopaudioButton                 matlab.ui.control.Button
        DownloadfilteredaudioButton     matlab.ui.control.Button
        PlayfilteredaudioButton         matlab.ui.control.Button
        PlayrawaudioButton              matlab.ui.control.Button
        UploadaudiofileButton           matlab.ui.control.Button
        CenterFrekvensmaxEditField      matlab.ui.control.EditField
        CenterFrekvensmaxEditFieldLabel  matlab.ui.control.Label
        CenterFrekvensminimumEditField  matlab.ui.control.EditField
        CenterFrekvensminimumLabel      matlab.ui.control.Label
        RangeslopeLabel                 matlab.ui.control.Label
        GainLabel                       matlab.ui.control.Label
        SpeedLabel                      matlab.ui.control.Label
        FiltertypeLabel                 matlab.ui.control.Label
        Panel_3                         matlab.ui.container.Panel
        HzSpinner                       matlab.ui.control.Spinner
        HzSpinnerLabel                  matlab.ui.control.Label
        RateSpinner                     matlab.ui.control.Spinner
        RateSpinnerLabel                matlab.ui.control.Label
        EditFieldActiveFilters          matlab.ui.control.EditField
        FilternumberSpinner             matlab.ui.control.Spinner
        FilternumberSpinnerLabel        matlab.ui.control.Label
        Slider_Qfactor_1                matlab.ui.control.Slider
        dBLabel                         matlab.ui.control.Label
        Lamp_1                          matlab.ui.control.Lamp
        Switch_1                        matlab.ui.control.Switch
        EditField_dBgain_1              matlab.ui.control.NumericEditField
        DropDown_filter_1               matlab.ui.control.DropDown
        Panel                           matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        AnalysisTab                     matlab.ui.container.Tab
        FilterinformationTextArea       matlab.ui.control.TextArea
        FilterinformationLabel          matlab.ui.control.Label
        CostoffilterTextArea            matlab.ui.control.TextArea
        CostoffilterLabel               matlab.ui.control.Label
        PhaseandbodeplotTab             matlab.ui.container.Tab
    end

    
    properties (Access = public)
        Properties
        fsample = 44000;
        N = 44000;
        f_oploesning = 44000/44000; % frekvens opløsning fsample/N
        f_akse = (0:44000/44000:44000 - 44000/44000); % (0:f_oploesning:fsample - f_oploesning);
        
        activeFiltersNum = 0;
        h_WahWah = cell(1,100);
        h_Phaser = cell(1,100);
        Hd;
        f_minFlag = true;
        f_maxFlag = false;
        a = zeros(1,3);
        b = zeros(1,3);
        
        %Filter audio file variables
        player;
        y_filteredSound;
        x_audioFile;
        Fs_audioFile;
        audioFileLoaded = false;
        playFilterAudio = false;
    end

    methods (Access = public)
        
        function init(app)
            %grid on; 
            xlim(app.UIAxes, [0, 22000]);
            ylim(app.UIAxes, [-15, 15]);
            xlabel(app.UIAxes, "Frekvens [Hz]");
            ylabel(app.UIAxes, "Magnitude [dB]");
            
            for i = 1:100
                app.h_WahWah{i} = ones(44000,1);
                app.h_Phaser{i} = ones(44000,1);
            end
            pause('on');
        end
        
        function updatePlot(app)
            
            text1 = sprintf(['a0: %d \na1: %d \na2: %d \n'], app.a(1), app.a(2), app.a(3));
            app.acoeffTextArea.Value = text1; %num2str(str2double(app.EditField_speed_1));
            text1 = sprintf(['b0: %d \nb1: %d \nb2: %d \n'], app.b(1), app.b(2), app.b(3));
            app.bcoeffTextArea.Value = text1; %num2str(str2double(app.EditField_speed_1));
            text1 = sprintf(['']);
            
            convActiveFilters(app);

            X_dB_norm = 20*log10(abs(app.Hd));
            semilogx(app.UIAxes, app.f_akse(1:end)/2, X_dB_norm(1:end));
            %plot(app.f_akse(1:end/2)/2, abs(app.Hd(1:end/2)))
            
            %sosfilt = dsp.SOSFilter(app.b, app.a);
            %sosStruct = cost(sosfilt);
            %text2 = sprintf('Number of Coefficients: %d \nNumber of States: %d \nMultiplications Per Input Sample: %d \nAdditions Per Input Sample: %d \n', sosStruct.NumCoefficients, sosStruct.NumStates, sosStruct.MultiplicationsPerInputSample, sosStruct.AdditionsPerInputSample);
            %app.CostoffilterTextArea.Value = text2;
        end 

        function convActiveFilters(app)
            app.Hd = ones(app.fsample,1);
            activeFilters_h = ones(app.fsample, app.activeFiltersNum);
            
            %if no filter is active then reset activeFilters_h to zeros
            if (app.activeFiltersNum == 0)
                app.Hd = ones(app.fsample,1);
                return;
            end

            %Check all switch components if filter is active
            %Then insert h into activeFilters
            for i = 1:app.activeFiltersNum
                if(app.DropDown_filter_1.Value == "Wah-wah")
                    activeFilters_h(:,i) = app.h_WahWah{i}; 
                end
                if(app.DropDown_filter_1.Value == "Phaser")
                    activeFilters_h(:,i) = app.h_Phaser{i}; 
                end
            end
            
            text1 = sprintf(['%d'], app.activeFiltersNum);
            app.EditFieldActiveFilters.Value = text1;

            %if there is only one filter active, then no cascade is nessesary
            %The active filters cascades together by cross correlation
            for i = 1:app.activeFiltersNum
                if (app.activeFiltersNum == 1)
                    app.Hd = activeFilters_h(:,i);
                end
                if (app.activeFiltersNum > 1)
                    app.Hd = app.Hd.*activeFilters_h(:,i);
                end
            end       
        end

        function bandPassFilter(app, w0, alpha_BW)
           
            % bandpass filter Coeff b
            b0 = sin(w0) / 2; 
            b1 = 0; 
            b2 = -sin(w0) / 2; 
            app.b = [b0, b1, b2];
            
            % bandpass filter Coeff a
            a0 = 1+alpha_BW; 
            a1 = -2*cos(w0); 
            a2 = 1-alpha_BW;
            app.a = [a0, a1, a2]; 
            
        end
                       
        function peakFilter(app, A, w0, alpha1)
            
            % Peak filter Coeff b
            b0 = 1 + alpha1*A; 
            b1 = -2*cos(w0); 
            b2 = 1 - alpha1*A;
            app.b = [b0, b1, b2]; 
            
            % Peak filter Coeff a
            a0 = 1+alpha1/A; 
            a1 = -2*cos(w0); 
            a2 = 1-(alpha1/A);
            app.a = [a0, a1, a2]; 
        end
        
        function notchFilter(app, w0, alpha1)
            
            % Notch filter Coeff b
            b0 = 1; 
            b1 = -2*cos(w0); 
            b2 = 1;
            app.b = [b0, b1, b2];
            
            % Notch filter Coeff a
            a0 = 1+alpha1; 
            a1 = -2*cos(w0); 
            a2 = 1-alpha1;
            app.a = [a0, a1, a2];
        end

        function bandpassFilterCalc(app, shiftFilterIndex, filterIndex)
                        
            f0_center = 150 + shiftFilterIndex;                         % Center frekvens
            shift = 400 * filterIndex;                                  % Shift 
            BW = log2(250+shiftFilterIndex) - log2(50+shiftFilterIndex);% Bandwidth
            
            w0 = 2*pi*((f0_center + shift) / app.fsample);              % Angle to the Low cut freq
            alpha_BW = (sin(w0)) * sinh((log(2)/2) * BW * (w0/sin(w0))); % Alpha with BW factor
            
            bandPassFilter(app, w0, alpha_BW);                          % Calculate lowpass a, b coeff 
            
            h1 = freqz(app.b, app.a, app.fsample); % Calculate transfer function
            app.h_WahWah{filterIndex+1} = h1;
            if ((f0_center + shift) >= app.CenterFrekvensmaxEditField.Value)
                app.direction = "decrement";
            elseif ((f0_center + shift) <= app.CenterFrekvensminimumEditField.Value)
                app.direction = "decrement";
            end
        end
        
        function filter1(app) 
            %text1 = sprintf(['Cutoff frekvens: %d \n Qfactor: %d \n Dropdown: %d \n'], app.EditField_speed_1.Value,  app.Slider_Qfactor_1.Value, app.DropDown_filter_1.Value);
            %app.acoeffTextArea.Value = text1; %num2str(str2double(app.EditField_speed_1));
            %text1 = sprintf(['']);

            % Angle to the freq
            %w0 = 2*pi*(app.EditField_speed_1.Value / app.fsample); 
            
            % Alpha with Q factor
            %alpha1 = (sin(w0)) / (2*app.Slider_Qfactor_1.Value); % S -> alpha2 = (sin(w0)/2) * sqrt((A+(1/A))*((1/slope)-1)+2)            
            
            %gain
            %A = 10^(app.EditField_dBgain_1.Value / 40);       % A only used in peaking and shelving filters
            shiftFilter = 0;
            f_max = str2num(app.CenterFrekvensmaxEditField.Value);
            f_min = str2num(app.CenterFrekvensminimumEditField.Value);
            filterOffset = 500;
            switch(app.DropDown_filter_1.Value)
                case 'Wah-wah' %bandpass
                    while 1
                        if (app.activeFiltersNum == 0)
                            return;
                        end
                        for i = 1:app.FilternumberSpinner.Value
                            shiftedVal = (50 + filterOffset*(i-1)) + shiftFilter;
                            w0 = 2*pi*(shiftedVal / app.fsample);
                            alpha1 = (sin(w0)) / (2*app.Slider_Qfactor_1.Value);
                            A = 10^(app.EditField_dBgain_1.Value / 40);
                                
                            peakFilter(app, A, w0, alpha1)
                            h = freqz(app.b,app.a,app.fsample);
                            app.h_WahWah{i} = h;
                            updatePlot(app)
                            pause(app.RateSpinner.Value)
                            
                            text1 = sprintf(['%d'], shiftedVal);
                            app.shiftingEditField.Value = text1;
                            
                            %Hvis f_min flag er sat, begynd at shift op
                            if(shiftedVal <= f_max && shiftedVal >= f_min && app.f_minFlag)
                                if (shiftedVal >= f_max && i == app.FilternumberSpinner.Value)
                                    shiftedVal = f_max;
                                    app.f_maxFlag = true;
                                    app.f_minFlag = false;
                                else
                                    shiftFilter = shiftFilter + app.HzSpinner.Value;
                                    app.f_maxFlag = false;
                                end
                            end
                            
                            %Hvis f_max flag er sat, begynd at shift ned
                            if(shiftedVal <= f_max && shiftedVal >= f_min && app.f_maxFlag)                         
                                if (shiftedVal <= f_min && i == 1)
                                    shiftedVal = f_min;
                                    app.f_minFlag = true;
                                    app.f_maxFlag = false;
                                else
                                    shiftFilter = shiftFilter - app.HzSpinner.Value;
                                    app.f_minFlag = false;  
                                end
                            end
                        end
                    end
                case 'Phaser' %notchfilter
                    while 1
                        if (app.activeFiltersNum == 0)
                            return;
                        end
                        for i = 1:app.FilternumberSpinner.Value
                            shiftedVal = (50 + filterOffset*(i-1)) + shiftFilter;
                            w0 = 2*pi*(shiftedVal / app.fsample);
                            alpha1 = (sin(w0)) / (2*app.Slider_Qfactor_1.Value);
                            
                            notchFilter(app, w0, alpha1);
                            h = freqz(app.b,app.a,app.fsample);
                            app.h_Phaser{i} = h;
                            updatePlot(app)
                            pause(app.RateSpinner.Value)
                            
                            text1 = sprintf(['%d'], shiftedVal);
                            app.shiftingEditField.Value = text1;
                            
                            %Hvis f_min flag er sat, begynd at shift op
                            if(shiftedVal <= f_max && shiftedVal >= f_min && app.f_minFlag)
                                if (shiftedVal >= f_max && i == app.FilternumberSpinner.Value)
                                    shiftedVal = f_max;
                                    app.f_maxFlag = true;
                                    app.f_minFlag = false;
                                else
                                    shiftFilter = shiftFilter + app.HzSpinner.Value;
                                    app.f_maxFlag = false;
                                end
                            end
                            
                            %Hvis f_max flag er sat, begynd at shift ned
                            if(shiftedVal <= f_max && shiftedVal >= f_min && app.f_maxFlag)                         
                                if (shiftedVal <= f_min && i == 1)
                                    shiftedVal = f_min;
                                    app.f_minFlag = true;
                                    app.f_maxFlag = false;
                                else
                                    shiftFilter = shiftFilter - app.HzSpinner.Value;
                                    app.f_minFlag = false;  
                                end
                            end
                        end
                    end
            end
        end
    end   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function initApp(app, f0)
            init(app);
            updatePlot(app);
        end

        % Callback function: DropDown_filter_1, EditField_dBgain_1, 
        % ...and 9 other components
        function filter_panel_1_cb(app, event)
           switch app.Switch_1.Value
               case 'On'
                app.Lamp_1.Color = 'green';
                app.Slider_Qfactor_1.Enable = 'on';
                app.DropDown_filter_1.Enable = 'on';
                app.DropDown_filter_1.Editable = 'on';
                app.EditField_dBgain_1.Enable = 'on';
                app.EditField_dBgain_1.Editable = 'on';
                app.RateSpinner.Enable = 'on';
                app.RateSpinner.Editable = 'on';
                app.HzSpinner.Enable = 'on';
                app.HzSpinner.Editable = 'on';
                app.FilternumberSpinner.Enable = 'on';
                app.FilternumberSpinner.Editable = 'on';
                app.EditField_dBgain_1.Enable = 'off';
                app.EditField_dBgain_1.Editable = 'off';               
                if(app.DropDown_filter_1.Value == "Wah-wah")
                    app.EditField_dBgain_1.Enable = 'on';
                    app.EditField_dBgain_1.Editable = 'on';
                end
                app.activeFiltersNum = app.FilternumberSpinner.Value;
                filter1(app);
               case 'Off' 
                app.Lamp_1.Color = 'red';
                app.Slider_Qfactor_1.Enable = 'off';
                app.DropDown_filter_1.Enable = 'off';
                app.DropDown_filter_1.Editable = 'off';
                app.EditField_dBgain_1.Enable = 'off';
                app.EditField_dBgain_1.Editable = 'off';
                app.RateSpinner.Enable = 'off';
                app.RateSpinner.Editable = 'off';
                app.HzSpinner.Enable = 'off';
                app.HzSpinner.Editable = 'off';
                app.FilternumberSpinner.Enable = 'off';
                app.FilternumberSpinner.Editable = 'off';
                app.FilternumberSpinner.Value = 0;
                updatePlot(app);
           end
        end

        % Button pushed function: UploadaudiofileButton
        function UploadaudiofileButtonPushed(app, event)
            [file,path] = uigetfile('*.*');  %open a file
            [app.x_audioFile, app.Fs_audioFile] = audioread([path file]);
            app.player = audioplayer(app.x_audioFile, app.Fs_audioFile);
            app.FileLabel.Text = file;
            app.audioFileLoaded = true;
            %Now we want to display the audio signal 
            N_audioFile = length(app.x_audioFile);
            f_akse_audioFile = (0:app.Fs_audioFile/48000:app.Fs_audioFile - app.Fs_audioFile/48000);
            X_norm = (abs(fft(app.x_audioFile, 48000)))/10; %divided by factor of 10
            figure;
            hold on;
            semilogx(app.UIAxes, f_akse_audioFile(1:0.5*end), X_norm(1:0.5*end))
            
        end

        % Button pushed function: SendtoanalysisButton
        function SendtoanalysisButtonPushed(app, event)
            fvtool(app.Hd)
        end

        % Button pushed function: PlayrawaudioButton
        function PlayrawaudioButtonPushed(app, event)
            
            play(app.player);
        end

        % Button pushed function: PlayfilteredaudioButton
        function PlayfilteredaudioButtonPushed(app, event)
            app.playFilterAudio = true;
        end

        % Button pushed function: StopaudioButton
        function StopaudioButtonPushed(app, event)
            app.playFilterAudio = false;
            stop(app.player);
        end

        % Button pushed function: DownloadfilteredaudioButton
        function DownloadfilteredaudioButtonPushed(app, event)
            %audiowrite("Audio files\" + app.FileLabel.Text + "_With_Effect", app.y_filteredSound, app.Fs_audioFile);
            msgbox("The file: _With_Effect_" + app.FileLabel.Text + " has been successfully saved to Audio files\","Success");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EqualizerTab = uitab(app.TabGroup);
            app.EqualizerTab.Title = 'Equalizer';
            app.Panel = uipanel(app.EqualizerTab);
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Phaser og Wahwah')
            xlabel(app.UIAxes, 'Frekvens')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.Panel_2 = uipanel(app.EqualizerTab);
            app.Panel_3 = uipanel(app.Panel_2);
            app.DropDown_filter_1 = uidropdown(app.Panel_3);
            app.DropDown_filter_1.Items = {'Phaser', 'Wah-wah'};
            app.DropDown_filter_1.DropDownOpeningFcn = createCallbackFcn(app, @filter_panel_1_cb, true);
            app.DropDown_filter_1.Enable = 'off';
            app.DropDown_filter_1.Value = 'Wah-wah';
            app.EditField_dBgain_1 = uieditfield(app.Panel_3, 'numeric');
            app.EditField_dBgain_1.Limits = [12 Inf];
            app.EditField_dBgain_1.ValueChangedFcn = createCallbackFcn(app, @filter_panel_1_cb, true);
            app.EditField_dBgain_1.Enable = 'off';
            app.EditField_dBgain_1.Value = 12;
            app.Switch_1 = uiswitch(app.Panel_3, 'slider');
            app.Switch_1.ValueChangedFcn = createCallbackFcn(app, @filter_panel_1_cb, true);
            app.Lamp_1 = uilamp(app.Panel_3);
            app.dBLabel = uilabel(app.Panel_3);
            app.dBLabel.Text = 'dB';
            app.Slider_Qfactor_1 = uislider(app.Panel_3);
            app.Slider_Qfactor_1.Limits = [2 10];
            app.Slider_Qfactor_1.ValueChangedFcn = createCallbackFcn(app, @filter_panel_1_cb, true);
            app.Slider_Qfactor_1.ValueChangingFcn = createCallbackFcn(app, @filter_panel_1_cb, true);
            app.Slider_Qfactor_1.Enable = 'off';
            app.Slider_Qfactor_1.Value = 2;
            app.FilternumberSpinnerLabel = uilabel(app.Panel_3);
            app.FilternumberSpinnerLabel.Enable = 'off';
            app.FilternumberSpinnerLabel.Text = 'Filter number';
            app.FilternumberSpinner = uispinner(app.Panel_3);
            app.FilternumberSpinner.ValueChangingFcn = createCallbackFcn(app, @filter_panel_1_cb, true);
            app.FilternumberSpinner.Limits = [0 100];
            app.FilternumberSpinner.ValueChangedFcn = createCallbackFcn(app, @filter_panel_1_cb, true);
            app.FilternumberSpinner.Enable = 'off';
            app.EditFieldActiveFilters = uieditfield(app.Panel_3, 'text');
            app.EditFieldActiveFilters.Value = '0';
            app.RateSpinnerLabel = uilabel(app.Panel_3);
            app.RateSpinnerLabel.Enable = 'off';
            app.RateSpinnerLabel.Text = 'Rate:';
            app.RateSpinner = uispinner(app.Panel_3);
            app.RateSpinner.Step = 0.01;
            app.RateSpinner.ValueChangingFcn = createCallbackFcn(app, @filter_panel_1_cb, true);
            app.RateSpinner.Limits = [0.01 1];
            app.RateSpinner.ValueChangedFcn = createCallbackFcn(app, @filter_panel_1_cb, true);
            app.RateSpinner.Enable = 'off';
            app.RateSpinner.Value = 1;
            app.HzSpinnerLabel = uilabel(app.Panel_3);
            app.HzSpinnerLabel.Enable = 'off';
            app.HzSpinnerLabel.Text = 'Hz:';
            app.HzSpinner = uispinner(app.Panel_3);
            app.HzSpinner.Step = 10;
            app.HzSpinner.ValueChangingFcn = createCallbackFcn(app, @filter_panel_1_cb, true);
            app.HzSpinner.Limits = [0 500];
            app.HzSpinner.ValueChangedFcn = createCallbackFcn(app, @filter_panel_1_cb, true);
            app.HzSpinner.Enable = 'off';
            app.FiltertypeLabel = uilabel(app.Panel_2);
            app.FiltertypeLabel.Text = 'Filter type';
            app.SpeedLabel = uilabel(app.Panel_2);
            app.SpeedLabel.Text = 'Speed';
            app.GainLabel = uilabel(app.Panel_2);
            app.GainLabel.Text = 'Gain';
            app.RangeslopeLabel = uilabel(app.Panel_2);
            app.RangeslopeLabel.Text = 'Range/slope';
            app.CenterFrekvensminimumLabel = uilabel(app.Panel_2);
            app.CenterFrekvensminimumLabel.Text = 'Center Frekvens minimum';
            app.CenterFrekvensminimumEditField = uieditfield(app.Panel_2, 'text');
            app.CenterFrekvensminimumEditField.Value = '50';
            app.CenterFrekvensmaxEditFieldLabel = uilabel(app.Panel_2);
            app.CenterFrekvensmaxEditFieldLabel.Text = 'Center Frekvens max';
            app.CenterFrekvensmaxEditField = uieditfield(app.Panel_2, 'text');
            app.CenterFrekvensmaxEditField.Value = '2000';
            app.Panel_4 = uipanel(app.Panel_2);
            app.UploadaudiofileButton = uibutton(app.Panel_4, 'push');
            app.UploadaudiofileButton.ButtonPushedFcn = createCallbackFcn(app, @UploadaudiofileButtonPushed, true);
            app.UploadaudiofileButton.Text = 'Upload audio file';
            app.PlayrawaudioButton = uibutton(app.Panel_4, 'push');
            app.PlayrawaudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayrawaudioButtonPushed, true);
            app.PlayrawaudioButton.Text = 'Play raw audio';
            app.PlayfilteredaudioButton = uibutton(app.Panel_4, 'push');
            app.PlayfilteredaudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayfilteredaudioButtonPushed, true);
            app.PlayfilteredaudioButton.Text = 'Play filtered audio';
            app.DownloadfilteredaudioButton = uibutton(app.Panel_4, 'push');
            app.DownloadfilteredaudioButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadfilteredaudioButtonPushed, true);
            app.DownloadfilteredaudioButton.Text = 'Download filtered audio';
            app.StopaudioButton = uibutton(app.Panel_4, 'push');
            app.StopaudioButton.ButtonPushedFcn = createCallbackFcn(app, @StopaudioButtonPushed, true);
            app.StopaudioButton.Text = 'Stop audio';
            app.SendtoanalysisButton = uibutton(app.Panel_2, 'push');
            app.SendtoanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @SendtoanalysisButtonPushed, true);
            app.SendtoanalysisButton.Text = 'Send to analysis';
            app.bcoeffLabel = uilabel(app.Panel_2);
            app.bcoeffLabel.Text = 'b coeff:';
            app.bcoeffTextArea = uitextarea(app.Panel_2);
            app.bcoeffTextArea.Editable = 'off';
            app.acoeffLabel = uilabel(app.Panel_2);
            app.acoeffLabel.Text = 'a coeff:';
            app.acoeffTextArea = uitextarea(app.Panel_2);
            app.acoeffTextArea.Editable = 'off';
            app.FileLabel = uilabel(app.Panel_2);
            app.FileLabel.Text = 'File:';
            app.shiftingEditFieldLabel = uilabel(app.Panel_2);
            app.shiftingEditFieldLabel.Text = 'shifting ->';
            app.shiftingEditField = uieditfield(app.Panel_2, 'text');
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.CostoffilterLabel = uilabel(app.AnalysisTab);
            app.CostoffilterLabel.Text = 'Cost of filter: ';
            app.CostoffilterTextArea = uitextarea(app.AnalysisTab);
            app.FilterinformationLabel = uilabel(app.AnalysisTab);
            app.FilterinformationLabel.Text = 'Filter information:';
            app.FilterinformationTextArea = uitextarea(app.AnalysisTab);
            app.PhaseandbodeplotTab = uitab(app.TabGroup);
            app.PhaseandbodeplotTab.Title = 'Phase and bode plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Parametric_EQ_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)initApp(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end